classdef CheckDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ReturntoMenuButton    matlab.ui.control.Button
        ReturntoSudokuButton  matlab.ui.control.Button
        QuitButton            matlab.ui.control.Button
        TitleLabel            matlab.ui.control.Label
        SubtitleLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        SudokuScreen % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sudoku, isSolved)
            app.SudokuScreen = sudoku;
            if isSolved
                app.TitleLabel.Text = 'Good job!';
                app.SubtitleLabel.Text = 'Your solution is correct';
            else
                app.TitleLabel.Text = 'So close!';
                app.SubtitleLabel.Text = 'Your solution is not correct';
            end
        end

        % Button pushed function: ReturntoSudokuButton
        function ReturntoSudokuButtonPushed(app, event)
            app.SudokuScreen.PauseResumeButton.Text = 'Pause';
            % Enable buttons
            app.SudokuScreen.CheckButton.Enable = 'on';
            app.SudokuScreen.PauseResumeButton.Enable = 'on';
            app.SudokuScreen.ReturntoMenuButton.Enable = 'on';
            app.SudokuScreen.QuitButton.Enable = 'on';
            % Enable editable cells
            disableEnableCells(app.SudokuScreen,'on');
            start(app.SudokuScreen.sudokuTimer);
            delete(app);
        end

        % Button pushed function: ReturntoMenuButton
        function ReturntoMenuButtonPushed(app, event)
            Menu();
            delete(app.SudokuScreen);
            delete(app);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app.SudokuScreen);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ReturntoMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMenuButtonPushed, true);
            app.ReturntoMenuButton.Text = 'Return to Menu';
            app.ReturntoSudokuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoSudokuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoSudokuButtonPushed, true);
            app.ReturntoSudokuButton.Text = 'Return to Sudoku';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title';
            app.SubtitleLabel = uilabel(app.UIFigure);
            app.SubtitleLabel.Text = 'Subtitle';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CheckDialog(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end