classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SUDOKULabel            matlab.ui.control.Label
        QuitButton             matlab.ui.control.Button
        StartGameButton        matlab.ui.control.Button
        DifficultyButtonGroup  matlab.ui.container.ButtonGroup
        EasyButton             matlab.ui.control.ToggleButton
        MediumButton           matlab.ui.control.ToggleButton
        HardButton             matlab.ui.control.ToggleButton
    end


    properties (Access = public)
        Difficulty = 16; % Variable storing the difficulty level of the sudoku. Medium as default.
    end

    properties (Access = private)
        PlayScreen % Sudoku screen app
    end

    methods (Access = private)
        
    end


    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app.PlayScreen); % Quits the sudoku screen
            delete(app); % Quits the app
        end

        % Selection changed function: DifficultyButtonGroup
        function DifficultyButtonGroupSelectionChanged(app, event)
            selectedButton = app.DifficultyButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Easy'
                    app.Difficulty = 12;
                case 'Medium'
                    app.Difficulty = 16;
                case 'Hard'
                    app.Difficulty = 20;
            end
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.PlayScreen = SudokuScreen(app.Difficulty); % Open the Sudoku screen
            delete(app); % Close the Menu screen
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SUDOKULabel = uilabel(app.UIFigure);
            app.SUDOKULabel.Text = 'SUDOKU';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.DifficultyButtonGroup = uibuttongroup(app.UIFigure);
            app.DifficultyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DifficultyButtonGroupSelectionChanged, true);
            app.DifficultyButtonGroup.Title = 'Difficulty';
            app.EasyButton = uitogglebutton(app.DifficultyButtonGroup);
            app.EasyButton.Text = 'Easy';
            app.MediumButton = uitogglebutton(app.DifficultyButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.MediumButton.Value = true;
            app.HardButton = uitogglebutton(app.DifficultyButtonGroup);
            app.HardButton.Text = 'Hard';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Menu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end