classdef SudokuScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ReturntoMenuButton  matlab.ui.control.Button
        PauseResumeButton   matlab.ui.control.Button
        TimeElapsedLabel    matlab.ui.control.Label
        QuitButton          matlab.ui.control.Button
        Panel_1             matlab.ui.container.Panel
        EditField_1         matlab.ui.control.NumericEditField
        EditField_2         matlab.ui.control.NumericEditField
        EditField_3         matlab.ui.control.NumericEditField
        EditField_10        matlab.ui.control.NumericEditField
        EditField_11        matlab.ui.control.NumericEditField
        EditField_12        matlab.ui.control.NumericEditField
        EditField_19        matlab.ui.control.NumericEditField
        EditField_20        matlab.ui.control.NumericEditField
        EditField_21        matlab.ui.control.NumericEditField
        Panel_2             matlab.ui.container.Panel
        EditField_4         matlab.ui.control.NumericEditField
        EditField_5         matlab.ui.control.NumericEditField
        EditField_6         matlab.ui.control.NumericEditField
        EditField_13        matlab.ui.control.NumericEditField
        EditField_14        matlab.ui.control.NumericEditField
        EditField_15        matlab.ui.control.NumericEditField
        EditField_22        matlab.ui.control.NumericEditField
        EditField_23        matlab.ui.control.NumericEditField
        EditField_24        matlab.ui.control.NumericEditField
        Panel_3             matlab.ui.container.Panel
        EditField_7         matlab.ui.control.NumericEditField
        EditField_8         matlab.ui.control.NumericEditField
        EditField_9         matlab.ui.control.NumericEditField
        EditField_16        matlab.ui.control.NumericEditField
        EditField_17        matlab.ui.control.NumericEditField
        EditField_18        matlab.ui.control.NumericEditField
        EditField_25        matlab.ui.control.NumericEditField
        EditField_26        matlab.ui.control.NumericEditField
        EditField_27        matlab.ui.control.NumericEditField
        Panel_4             matlab.ui.container.Panel
        EditField_28        matlab.ui.control.NumericEditField
        EditField_29        matlab.ui.control.NumericEditField
        EditField_30        matlab.ui.control.NumericEditField
        EditField_37        matlab.ui.control.NumericEditField
        EditField_38        matlab.ui.control.NumericEditField
        EditField_39        matlab.ui.control.NumericEditField
        EditField_46        matlab.ui.control.NumericEditField
        EditField_47        matlab.ui.control.NumericEditField
        EditField_48        matlab.ui.control.NumericEditField
        Panel_7             matlab.ui.container.Panel
        EditField_55        matlab.ui.control.NumericEditField
        EditField_56        matlab.ui.control.NumericEditField
        EditField_57        matlab.ui.control.NumericEditField
        EditField_64        matlab.ui.control.NumericEditField
        EditField_65        matlab.ui.control.NumericEditField
        EditField_66        matlab.ui.control.NumericEditField
        EditField_73        matlab.ui.control.NumericEditField
        EditField_74        matlab.ui.control.NumericEditField
        EditField_75        matlab.ui.control.NumericEditField
        Panel_5             matlab.ui.container.Panel
        EditField_31        matlab.ui.control.NumericEditField
        EditField_32        matlab.ui.control.NumericEditField
        EditField_33        matlab.ui.control.NumericEditField
        EditField_40        matlab.ui.control.NumericEditField
        EditField_41        matlab.ui.control.NumericEditField
        EditField_42        matlab.ui.control.NumericEditField
        EditField_49        matlab.ui.control.NumericEditField
        EditField_50        matlab.ui.control.NumericEditField
        EditField_51        matlab.ui.control.NumericEditField
        Panel_8             matlab.ui.container.Panel
        EditField_58        matlab.ui.control.NumericEditField
        EditField_59        matlab.ui.control.NumericEditField
        EditField_60        matlab.ui.control.NumericEditField
        EditField_67        matlab.ui.control.NumericEditField
        EditField_68        matlab.ui.control.NumericEditField
        EditField_69        matlab.ui.control.NumericEditField
        EditField_76        matlab.ui.control.NumericEditField
        EditField_77        matlab.ui.control.NumericEditField
        EditField_78        matlab.ui.control.NumericEditField
        Panel_6             matlab.ui.container.Panel
        EditField_34        matlab.ui.control.NumericEditField
        EditField_35        matlab.ui.control.NumericEditField
        EditField_36        matlab.ui.control.NumericEditField
        EditField_43        matlab.ui.control.NumericEditField
        EditField_44        matlab.ui.control.NumericEditField
        EditField_45        matlab.ui.control.NumericEditField
        EditField_52        matlab.ui.control.NumericEditField
        EditField_53        matlab.ui.control.NumericEditField
        EditField_54        matlab.ui.control.NumericEditField
        Panel_9             matlab.ui.container.Panel
        EditField_61        matlab.ui.control.NumericEditField
        EditField_62        matlab.ui.control.NumericEditField
        EditField_63        matlab.ui.control.NumericEditField
        EditField_70        matlab.ui.control.NumericEditField
        EditField_71        matlab.ui.control.NumericEditField
        EditField_72        matlab.ui.control.NumericEditField
        EditField_79        matlab.ui.control.NumericEditField
        EditField_80        matlab.ui.control.NumericEditField
        EditField_81        matlab.ui.control.NumericEditField
        CheckButton         matlab.ui.control.Button
        CheckLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        SolutionGrid % Generated Sudoku grid that is the solution for the game
        UserGrid % Sudoku grid that the user will work with
        seed_1 = 'hcdegfaibeagibcdhfbifhadceggdhacefbiaeidfbgchcfbghieadfgcbiahdeihecdgbfadbafehigc';
        seed_2 = 'fdechiagbgcifabhedabhgedficcibedaghfdhabgficeegfhicbdahedabgcfibfgicedahiacdfhebg';
        seed_3 = 'icefhdbgaadgbcifhefhbagedcigiacdhefbcbdgefaihefhibacdgdgieachbfhefdibgacbachfgied';
        zeroRow % Array of rows that contain an initially emptied cell with zeroCol the location of the initial empty cells can be reconstructed
        zeroCol % Array of cols that contain an initially emptied cell
        Difficulty % stores difficulty value
    end

    properties (Access = public)
        sudokuTimer % Timer object % Description
    end
    
    methods (Access = private)

        function createUserSudoku(app, difficulty)
            % Creates the grid that will be shown to the user by removing values from the SolutionGrid.
            % The number of values to be removed will be difficulty. They will be removed random
            app.zeroRow = zeros(1,difficulty);
            app.zeroCol = app.zeroRow;
            
            for i = 1:difficulty
                row = randi(9);
                col = randi(9);
                % Randomly look for a non-empty position in the grid
                while app.UserGrid(row,col) == 0
                    row = randi(9);
                    col = randi(9);
                end
                app.UserGrid(row,col) = 0;
                app.zeroRow(i) = row;
                app.zeroCol(i) = col;
            end
            
        end
        
        function generateSudoku(app,difficulty)
            % createDiagonalGrid(app);
            % fillNonDiagonal(app);
            app.Difficulty = difficulty;
            createSolutionGrid(app);
            app.UserGrid = app.SolutionGrid;
            createUserSudoku(app,difficulty);
            fillUserSudoku(app);
        end
        
        function setCellValue(app, val, row, col)
            % Set the given value to the chosen cell
            % It also bolds, makes it not editable and slightly changes the background color
            index = ['EditField_', num2str(col + (row-1)*9)];
            app.(index).Value = val;
            app.(index).FontWeight = 'bold';
            app.(index).Editable = 'off';
            app.(index).BackgroundColor = [0.95 0.95 0.95];
        end
        
        function fillUserSudoku(app)
            % Fills the Edit Field grid with the User Grid generated values.
            for row = 1:9
                for col = 1:9
                    if app.UserGrid(row,col) ~= 0
                        setCellValue(app,app.UserGrid(row,col),row,col)
                    end
                end
            end
        end
        
        function createSolutionGrid(app)
            % Take a random seed
            seedNumber = randi(3);
            seed = app.(['seed_', num2str(seedNumber)]);
            % Shuffle the seed and reshape it to get SolutionGrid
            app.SolutionGrid = reshape(shuffleSeed(app, seed),9,9);
        end
        
        function shuffledSeed = shuffleSeed(app, seed)
            % Randomizes the correspondence between the characters a-i and the numbers 1-9 and assigns them
            shuffledSeed = zeros(1,81);
            lookup = randperm(9);
            for i = 1:81
               switch seed(i)
                   case 'a'
                       shuffledSeed(i) = lookup(1);
                   case 'b'
                       shuffledSeed(i) = lookup(2);
                   case 'c'
                       shuffledSeed(i) = lookup(3);
                   case 'd'
                       shuffledSeed(i) = lookup(4);
                   case 'e'
                       shuffledSeed(i) = lookup(5);
                   case 'f'
                       shuffledSeed(i) = lookup(6);
                   case 'g'
                       shuffledSeed(i) = lookup(7);
                   case 'h'
                       shuffledSeed(i) = lookup(8);
                   case 'i'
                       shuffledSeed(i) = lookup(9); 
               end
            end
        end
        
        function grid = collectBoard(app)
            % Function to collect values from the editField's into a matrix
            grid = app.UserGrid;
            % Collect values of the editable cells of the board and put them into grid
            for i = 1:app.Difficulty
                row = app.zeroRow(i);
                col = app.zeroCol(i);
                index = ['EditField_', num2str(col + (row-1)*9)];
                grid(row,col) = app.(index).Value;
            end
        end
        
        
    end

    methods (Access = public)
    
        function disableEnableCells(app,enable)
            for i = 1:app.Difficulty
                row = app.zeroRow(i);
                col = app.zeroCol(i);
                index = ['EditField_', num2str(col + (row-1)*9)];
                app.(index).Enable = enable;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, difficulty)
            generateSudoku(app, difficulty);
            t = 0;
            app.sudokuTimer = timer('ExecutionMode','fixedRate','Period',1,'TimerFcn',{@stopwatch,app});
            start(app.sudokuTimer);
            
            function stopwatch(obj, event, app)
                % Timer
                t = t+1;
                sec = mod(t,60);
                min = mod(floor(t/60),60);
                h = floor(t/3600);
                app.TimeElapsedLabel.Text = [num2str(h), ':', num2str(min), ':', num2str(sec)];
            end
            
        end

        % Button pushed function: ReturntoMenuButton
        function ReturntoMenuButtonPushed(app, event)
            Menu();
            stop(app.sudokuTimer);
            delete(app.sudokuTimer);
            delete(app);
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            enable = 'off';
            switch app.PauseResumeButton.Text
                case 'Pause'
                    app.PauseResumeButton.Text = 'Resume';
                    stop(app.sudokuTimer);
                case 'Resume'
                    app.PauseResumeButton.Text = 'Pause';
                    start(app.sudokuTimer);
                    enable = 'on';
            end
            
            % Disables or enables all editable cells
            disableEnableCells(app,enable);
            
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            stop(app.sudokuTimer);
            delete(app.sudokuTimer);
            delete(app);
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            % Collects grid from the board
            grid = collectBoard(app);
            % Check if it is complete
            if any(find(~grid))
                app.CheckLabel.Text = 'Please, complete the Sudoku before checking.';
            else
                % Disables buttons
                app.CheckButton.Enable = 'off';
                app.PauseResumeButton.Enable = 'off';
                app.ReturntoMenuButton.Enable = 'off';
                app.QuitButton.Enable = 'off';
                % Disables editable cells
                disableEnableCells(app,'off');
                % Pause timer
                app.PauseResumeButton.Text = 'Resume';
                stop(app.sudokuTimer);
                isCorrect = false;
                if isequal(grid,app.SolutionGrid)
                    isCorrect = true;
                end
                CheckDialog(app,isCorrect);    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ReturntoMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMenuButtonPushed, true);
            app.ReturntoMenuButton.Text = 'Return to Menu';
            app.PauseResumeButton = uibutton(app.UIFigure, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause';
            app.TimeElapsedLabel = uilabel(app.UIFigure);
            app.TimeElapsedLabel.Text = 'Time Elapsed';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.Panel_1 = uipanel(app.UIFigure);
            app.EditField_1 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_1.Limits = [0 9];
            app.EditField_1.ValueDisplayFormat = '%.0f';
            app.EditField_2 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_2.Limits = [0 9];
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_3 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_3.Limits = [0 9];
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.EditField_10 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_10.Limits = [0 9];
            app.EditField_10.ValueDisplayFormat = '%.0f';
            app.EditField_11 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_11.Limits = [0 9];
            app.EditField_11.ValueDisplayFormat = '%.0f';
            app.EditField_12 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_12.Limits = [0 9];
            app.EditField_12.ValueDisplayFormat = '%.0f';
            app.EditField_19 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_19.Limits = [0 9];
            app.EditField_19.ValueDisplayFormat = '%.0f';
            app.EditField_20 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_20.Limits = [0 9];
            app.EditField_20.ValueDisplayFormat = '%.0f';
            app.EditField_21 = uieditfield(app.Panel_1, 'numeric');
            app.EditField_21.Limits = [0 9];
            app.EditField_21.ValueDisplayFormat = '%.0f';
            app.Panel_2 = uipanel(app.UIFigure);
            app.EditField_4 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_4.Limits = [0 9];
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_5 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_5.Limits = [0 9];
            app.EditField_5.ValueDisplayFormat = '%.0f';
            app.EditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_6.Limits = [0 9];
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.EditField_13 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_13.Limits = [0 9];
            app.EditField_13.ValueDisplayFormat = '%.0f';
            app.EditField_14 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_14.Limits = [0 9];
            app.EditField_14.ValueDisplayFormat = '%.0f';
            app.EditField_15 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_15.Limits = [0 9];
            app.EditField_15.ValueDisplayFormat = '%.0f';
            app.EditField_22 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_22.Limits = [0 9];
            app.EditField_22.ValueDisplayFormat = '%.0f';
            app.EditField_23 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_23.Limits = [0 9];
            app.EditField_23.ValueDisplayFormat = '%.0f';
            app.EditField_24 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_24.Limits = [0 9];
            app.EditField_24.ValueDisplayFormat = '%.0f';
            app.Panel_3 = uipanel(app.UIFigure);
            app.EditField_7 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_7.Limits = [0 9];
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_8 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_8.Limits = [0 9];
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_9 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_9.Limits = [0 9];
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_16 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_16.Limits = [0 9];
            app.EditField_16.ValueDisplayFormat = '%.0f';
            app.EditField_17 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_17.Limits = [0 9];
            app.EditField_17.ValueDisplayFormat = '%.0f';
            app.EditField_18 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_18.Limits = [0 9];
            app.EditField_18.ValueDisplayFormat = '%.0f';
            app.EditField_25 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_25.Limits = [0 9];
            app.EditField_25.ValueDisplayFormat = '%.0f';
            app.EditField_26 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_26.Limits = [0 9];
            app.EditField_26.ValueDisplayFormat = '%.0f';
            app.EditField_27 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_27.Limits = [0 9];
            app.EditField_27.ValueDisplayFormat = '%.0f';
            app.Panel_4 = uipanel(app.UIFigure);
            app.EditField_28 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_28.Limits = [0 9];
            app.EditField_28.ValueDisplayFormat = '%.0f';
            app.EditField_29 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_29.Limits = [0 9];
            app.EditField_29.ValueDisplayFormat = '%.0f';
            app.EditField_30 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_30.Limits = [0 9];
            app.EditField_30.ValueDisplayFormat = '%.0f';
            app.EditField_37 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_37.Limits = [0 9];
            app.EditField_37.ValueDisplayFormat = '%.0f';
            app.EditField_38 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_38.Limits = [0 9];
            app.EditField_38.ValueDisplayFormat = '%.0f';
            app.EditField_39 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_39.Limits = [0 9];
            app.EditField_39.ValueDisplayFormat = '%.0f';
            app.EditField_46 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_46.Limits = [0 9];
            app.EditField_46.ValueDisplayFormat = '%.0f';
            app.EditField_47 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_47.Limits = [0 9];
            app.EditField_47.ValueDisplayFormat = '%.0f';
            app.EditField_48 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_48.Limits = [0 9];
            app.EditField_48.ValueDisplayFormat = '%.0f';
            app.Panel_7 = uipanel(app.UIFigure);
            app.EditField_55 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_55.Limits = [0 9];
            app.EditField_55.ValueDisplayFormat = '%.0f';
            app.EditField_56 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_56.Limits = [0 9];
            app.EditField_56.ValueDisplayFormat = '%.0f';
            app.EditField_57 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_57.Limits = [0 9];
            app.EditField_57.ValueDisplayFormat = '%.0f';
            app.EditField_64 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_64.Limits = [0 9];
            app.EditField_64.ValueDisplayFormat = '%.0f';
            app.EditField_65 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_65.Limits = [0 9];
            app.EditField_65.ValueDisplayFormat = '%.0f';
            app.EditField_66 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_66.Limits = [0 9];
            app.EditField_66.ValueDisplayFormat = '%.0f';
            app.EditField_73 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_73.Limits = [0 9];
            app.EditField_73.ValueDisplayFormat = '%.0f';
            app.EditField_74 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_74.Limits = [0 9];
            app.EditField_74.ValueDisplayFormat = '%.0f';
            app.EditField_75 = uieditfield(app.Panel_7, 'numeric');
            app.EditField_75.Limits = [0 9];
            app.EditField_75.ValueDisplayFormat = '%.0f';
            app.Panel_5 = uipanel(app.UIFigure);
            app.EditField_31 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_31.Limits = [0 9];
            app.EditField_31.ValueDisplayFormat = '%.0f';
            app.EditField_32 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_32.Limits = [0 9];
            app.EditField_32.ValueDisplayFormat = '%.0f';
            app.EditField_33 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_33.Limits = [0 9];
            app.EditField_33.ValueDisplayFormat = '%.0f';
            app.EditField_40 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_40.Limits = [0 9];
            app.EditField_40.ValueDisplayFormat = '%.0f';
            app.EditField_41 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_41.Limits = [0 9];
            app.EditField_41.ValueDisplayFormat = '%.0f';
            app.EditField_42 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_42.Limits = [0 9];
            app.EditField_42.ValueDisplayFormat = '%.0f';
            app.EditField_49 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_49.Limits = [0 9];
            app.EditField_49.ValueDisplayFormat = '%.0f';
            app.EditField_50 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_50.Limits = [0 9];
            app.EditField_50.ValueDisplayFormat = '%.0f';
            app.EditField_51 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_51.Limits = [0 9];
            app.EditField_51.ValueDisplayFormat = '%.0f';
            app.Panel_8 = uipanel(app.UIFigure);
            app.EditField_58 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_58.Limits = [0 9];
            app.EditField_58.ValueDisplayFormat = '%.0f';
            app.EditField_59 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_59.Limits = [0 9];
            app.EditField_59.ValueDisplayFormat = '%.0f';
            app.EditField_60 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_60.Limits = [0 9];
            app.EditField_60.ValueDisplayFormat = '%.0f';
            app.EditField_67 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_67.Limits = [0 9];
            app.EditField_67.ValueDisplayFormat = '%.0f';
            app.EditField_68 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_68.Limits = [0 9];
            app.EditField_68.ValueDisplayFormat = '%.0f';
            app.EditField_69 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_69.Limits = [0 9];
            app.EditField_69.ValueDisplayFormat = '%.0f';
            app.EditField_76 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_76.Limits = [0 9];
            app.EditField_76.ValueDisplayFormat = '%.0f';
            app.EditField_77 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_77.Limits = [0 9];
            app.EditField_77.ValueDisplayFormat = '%.0f';
            app.EditField_78 = uieditfield(app.Panel_8, 'numeric');
            app.EditField_78.Limits = [0 9];
            app.EditField_78.ValueDisplayFormat = '%.0f';
            app.Panel_6 = uipanel(app.UIFigure);
            app.EditField_34 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_34.Limits = [0 9];
            app.EditField_34.ValueDisplayFormat = '%.0f';
            app.EditField_35 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_35.Limits = [0 9];
            app.EditField_35.ValueDisplayFormat = '%.0f';
            app.EditField_36 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_36.Limits = [0 9];
            app.EditField_36.ValueDisplayFormat = '%.0f';
            app.EditField_43 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_43.Limits = [0 9];
            app.EditField_43.ValueDisplayFormat = '%.0f';
            app.EditField_44 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_44.Limits = [0 9];
            app.EditField_44.ValueDisplayFormat = '%.0f';
            app.EditField_45 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_45.Limits = [0 9];
            app.EditField_45.ValueDisplayFormat = '%.0f';
            app.EditField_52 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_52.Limits = [0 9];
            app.EditField_52.ValueDisplayFormat = '%.0f';
            app.EditField_53 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_53.Limits = [0 9];
            app.EditField_53.ValueDisplayFormat = '%.0f';
            app.EditField_54 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_54.Limits = [0 9];
            app.EditField_54.ValueDisplayFormat = '%.0f';
            app.Panel_9 = uipanel(app.UIFigure);
            app.EditField_61 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_61.Limits = [0 9];
            app.EditField_61.ValueDisplayFormat = '%.0f';
            app.EditField_62 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_62.Limits = [0 9];
            app.EditField_62.ValueDisplayFormat = '%.0f';
            app.EditField_63 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_63.Limits = [0 9];
            app.EditField_63.ValueDisplayFormat = '%.0f';
            app.EditField_70 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_70.Limits = [0 9];
            app.EditField_70.ValueDisplayFormat = '%.0f';
            app.EditField_71 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_71.Limits = [0 9];
            app.EditField_71.ValueDisplayFormat = '%.0f';
            app.EditField_72 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_72.Limits = [0 9];
            app.EditField_72.ValueDisplayFormat = '%.0f';
            app.EditField_79 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_79.Limits = [0 9];
            app.EditField_79.ValueDisplayFormat = '%.0f';
            app.EditField_80 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_80.Limits = [0 9];
            app.EditField_80.ValueDisplayFormat = '%.0f';
            app.EditField_81 = uieditfield(app.Panel_9, 'numeric');
            app.EditField_81.Limits = [0 9];
            app.EditField_81.ValueDisplayFormat = '%.0f';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.CheckLabel = uilabel(app.UIFigure);
            app.CheckLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SudokuScreen(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end